classdef BER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptCohBERcurveUIFigure         matlab.ui.Figure
        ProjectnameEditField           matlab.ui.control.EditField
        ProjectnameEditFieldLabel      matlab.ui.control.Label
        SaveFiguresandResultsCheckBox  matlab.ui.control.CheckBox
        N2EditField                    matlab.ui.control.NumericEditField
        N2EditFieldLabel               matlab.ui.control.Label
        BackButton                     matlab.ui.control.Button
        StartSimulationButton          matlab.ui.control.Button
        DampingFactorEditField         matlab.ui.control.NumericEditField
        DampingFactorEditFieldLabel    matlab.ui.control.Label
        N1EditField                    matlab.ui.control.NumericEditField
        N1EditFieldLabel               matlab.ui.control.Label
        mu_2EditField                  matlab.ui.control.NumericEditField
        mu2Label                       matlab.ui.control.Label
        mu_1EditField                  matlab.ui.control.NumericEditField
        mu1Label                       matlab.ui.control.Label
        FreqoffsetHzEditField          matlab.ui.control.NumericEditField
        FreqoffsetHzEditFieldLabel     matlab.ui.control.Label
        NumberofpointsEditField        matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel   matlab.ui.control.Label
        NumperoftapsEditField          matlab.ui.control.NumericEditField
        NumperoftapsEditFieldLabel     matlab.ui.control.Label
        PolrotationradsEditField       matlab.ui.control.NumericEditField
        PolrotationradsEditFieldLabel  matlab.ui.control.Label
        OSNRenddBEditField             matlab.ui.control.NumericEditField
        OSNRenddBLabel                 matlab.ui.control.Label
        DeltanuHzEditField             matlab.ui.control.NumericEditField
        DeltanuHzEditFieldLabel        matlab.ui.control.Label
        OSNRstartdBEditField           matlab.ui.control.NumericEditField
        OSNRstartdBEditFieldLabel      matlab.ui.control.Label
        EqualizationLabel              matlab.ui.control.Label
        LinkModelLabel                 matlab.ui.control.Label
        SimulationLabel                matlab.ui.control.Label
    end


    properties (Access = private)
        Rs
        r% Description
        EQ_mode
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r, Rs, EQmode)
            if r==1
                app.OSNRstartdBEditField.Value = 3;
                app.OSNRenddBEditField.Value = 11;
                app.NumberofpointsEditField.Value = 1 + app.OSNRenddBEditField.Value-app.OSNRstartdBEditField.Value;
            elseif r==2
                app.OSNRstartdBEditField.Value = 11;
                app.OSNRenddBEditField.Value = 18;
                app.NumberofpointsEditField.Value = 1 + app.OSNRenddBEditField.Value-app.OSNRstartdBEditField.Value;
            else
                app.OSNRstartdBEditField.Value = 19;
                app.OSNRenddBEditField.Value = 25;
                app.NumberofpointsEditField.Value = 1 + app.OSNRenddBEditField.Value-app.OSNRstartdBEditField.Value;

                if Rs==128
                    app.PolrotationradsEditField.Value = 1e3;
                else
                    app.PolrotationradsEditField.Value = 1e2;
                end
            end


            if strcmp(EQmode,'LMS')
                app.N1EditFieldLabel.Text = 'Length pilot seq.';
                app.N2EditFieldLabel.Visible = 'off';
                app.N2EditField.Visible = 'off';
                app.DampingFactorEditFieldLabel.Visible = 'off';
                app.DampingFactorEditField.Visible = 'off';

                if r==1
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 9;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 9e-4;     
                    
                    else
                        app.NumperoftapsEditField.Value = 11;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 9e-4;
                    end
                elseif r==2
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 21;
                        app.mu_1EditField.Value = 9e-4;
                        app.mu_2EditField.Value = 9e-5;
                    else
                        app.NumperoftapsEditField.Value = 15;
                        app.mu_1EditField.Value = 9e-4;
                        app.mu_2EditField.Value = 9e-5;
                    end
                else
                    app.NumperoftapsEditField.Value = 15;
                    app.mu_1EditField.Value = 9e-4;
                    app.mu_2EditField.Value = 1e-4;                    
                end
            else
                app.N1EditFieldLabel.Text = 'CMA to RDE';
                app.N2EditFieldLabel.Text = 'Change Learn rate';
                if r == 1
                    app.N1EditFieldLabel.Visible = 'off';
                    app.N1EditField.Visible = 'off';
                end

                if r==1
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 11;
                        app.DampingFactorEditField.Value = 31.6;
                    else
                        app.NumperoftapsEditField.Value = 8;
                        app.DampingFactorEditField.Value = 150;
                    end

                    app.mu_1EditField.Value = 8e-3;
                    app.mu_2EditField.Value = 8e-4;

                else
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 15;
                        app.DampingFactorEditField.Value = 11.6;
                        app.mu_1EditField.Value = 8e-3;
                        app.mu_2EditField.Value = 8e-4;

                    elseif Rs == 100
                        app.NumperoftapsEditField.Value = 13;
                        app.DampingFactorEditField.Value = 21.6;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 1e-3;                  

                    else
                        app.NumperoftapsEditField.Value = 13;
                        app.DampingFactorEditField.Value = 11.6;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 1e-3;
                    end
                end

                
                app.N1EditField.Value = 1e3;
                app.N2EditField.Value = 3e5;

            end
            app.Rs = Rs;
            app.r = r;
            app.EQ_mode = EQmode;
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            if app.OSNRstartdBEditField.Value>app.OSNRenddBEditField.Value
                f = msgbox("'OSNR start' must be smaller than 'OSNR end'");
            else
                app.OptCohBERcurveUIFigure.Visible = 'off';
                f = uifigure('Name','OptCoh', 'Position', [100, 100, 300, 150]);
                lbl = uilabel(f, 'Text', 'Setting up Simulation', ...
                    'Position', [20, 50, 260, 50], ...
                    'HorizontalAlignment', 'center');
                drawnow;
                GUI_main_BER(app.r,app.Rs, app.OSNRstartdBEditField.Value, app.OSNRenddBEditField.Value, app.NumberofpointsEditField.Value, ...
                    app.DeltanuHzEditField.Value, app.PolrotationradsEditField.Value, app.FreqoffsetHzEditField.Value, app.EQ_mode, app.NumperoftapsEditField.Value, app.mu_1EditField.Value, ...
                    app.mu_2EditField.Value, app.N1EditField.Value, app.N2EditField.Value, app.DampingFactorEditField.Value, app.SaveFiguresandResultsCheckBox.Value,app.ProjectnameEditField.Value,lbl);
                close(f);
                app.OptCohBERcurveUIFigure.Visible = 'on';
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            mainApp = main;
            app.OptCohBERcurveUIFigure.Visible = 'off';
            mainApp.OptCohUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptCohBERcurveUIFigure = uifigure('Visible', 'off');
            app.OptCohBERcurveUIFigure.Name = 'OptCoh - BER curve';
            app.OptCohBERcurveUIFigure.Icon = 'OptcohLOGO.png';
            app.SimulationLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.SimulationLabel.Text = 'Simulation';
            app.LinkModelLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.LinkModelLabel.Text = 'Link Model';
            app.EqualizationLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.EqualizationLabel.Text = 'Equalization';
            app.OSNRstartdBEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.OSNRstartdBEditFieldLabel.Text = 'OSNR start [dB]';
            app.OSNRstartdBEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.OSNRstartdBEditField.Value = 5;
            app.DeltanuHzEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.DeltanuHzEditFieldLabel.Interpreter = 'latex';
            app.DeltanuHzEditFieldLabel.Text = '$\Delta \nu$ [Hz]';
            app.DeltanuHzEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.DeltanuHzEditField.Limits = [0 Inf];
            app.DeltanuHzEditField.ValueDisplayFormat = '%1.2e';
            app.DeltanuHzEditField.Value = 50000;
            app.OSNRenddBLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.OSNRenddBLabel.Text = 'OSNR end [dB]';
            app.OSNRenddBEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.OSNRenddBEditField.Value = 20;
            app.PolrotationradsEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.PolrotationradsEditFieldLabel.Text = 'Pol. rotation [rad/s]';
            app.PolrotationradsEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.PolrotationradsEditField.Limits = [0 Inf];
            app.PolrotationradsEditField.ValueDisplayFormat = '%1.2e';
            app.PolrotationradsEditField.Value = 1000;
            app.NumperoftapsEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.NumperoftapsEditFieldLabel.Text = 'Numper of taps';
            app.NumperoftapsEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.NumperoftapsEditField.Limits = [1 Inf];
            app.NumperoftapsEditField.Value = 31;
            app.NumberofpointsEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.NumberofpointsEditField.Limits = [1 Inf];
            app.NumberofpointsEditField.Value = 10;
            app.FreqoffsetHzEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.FreqoffsetHzEditFieldLabel.Text = 'Freq. offset [Hz]';
            app.FreqoffsetHzEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.FreqoffsetHzEditField.Value = 1000000;
            app.mu1Label = uilabel(app.OptCohBERcurveUIFigure);
            app.mu1Label.Interpreter = 'latex';
            app.mu1Label.Text = '$\mu_1$';
            app.mu_1EditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.mu_1EditField.Limits = [0 1];
            app.mu_1EditField.ValueDisplayFormat = '%1.2e';
            app.mu_1EditField.Value = 1e-05;
            app.mu2Label = uilabel(app.OptCohBERcurveUIFigure);
            app.mu2Label.Interpreter = 'latex';
            app.mu2Label.Text = '$\mu_2$';
            app.mu_2EditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.mu_2EditField.Limits = [0 1];
            app.mu_2EditField.ValueDisplayFormat = '%1.2e';
            app.mu_2EditField.Value = 1e-05;
            app.N1EditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.N1EditField.Limits = [0 Inf];
            app.N1EditField.ValueDisplayFormat = '%1.2e';
            app.N1EditField.Value = 100000;
            app.DampingFactorEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.DampingFactorEditFieldLabel.Text = 'Damping Factor';
            app.DampingFactorEditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.DampingFactorEditField.Limits = [0 500];
            app.DampingFactorEditField.Value = 150;
            app.StartSimulationButton = uibutton(app.OptCohBERcurveUIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.BackButton = uibutton(app.OptCohBERcurveUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.N2EditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.OptCohBERcurveUIFigure, 'numeric');
            app.N2EditField.Limits = [0 Inf];
            app.N2EditField.ValueDisplayFormat = '%1.2e';
            app.N2EditField.Value = 100000;
            app.SaveFiguresandResultsCheckBox = uicheckbox(app.OptCohBERcurveUIFigure);
            app.SaveFiguresandResultsCheckBox.Text = 'Save Figures and Results';
            app.ProjectnameEditFieldLabel = uilabel(app.OptCohBERcurveUIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.OptCohBERcurveUIFigure, 'text');
            % Show the figure after all components are created
            app.OptCohBERcurveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BER(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptCohBERcurveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptCohBERcurveUIFigure)
        end
    end
end