classdef Scatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptCohScatterplotUIFigure      matlab.ui.Figure
        ProjectnameEditField           matlab.ui.control.EditField
        ProjectnameEditFieldLabel      matlab.ui.control.Label
        SaveFiguresandResultsCheckBox  matlab.ui.control.CheckBox
        PhasecompoutputCheckBox        matlab.ui.control.CheckBox
        EQoutputCheckBox               matlab.ui.control.CheckBox
        FreqcompoutputCheckBox         matlab.ui.control.CheckBox
        CDcompoutputCheckBox           matlab.ui.control.CheckBox
        ReceiverinputCheckBox          matlab.ui.control.CheckBox
        ScatterplotsLabel              matlab.ui.control.Label
        N2EditField                    matlab.ui.control.NumericEditField
        N2EditFieldLabel               matlab.ui.control.Label
        DampingFactorEditField         matlab.ui.control.NumericEditField
        DampingFactorEditFieldLabel    matlab.ui.control.Label
        N1EditField                    matlab.ui.control.NumericEditField
        N1EditFieldLabel               matlab.ui.control.Label
        mu_2EditField                  matlab.ui.control.NumericEditField
        mu_2EditFieldLabel             matlab.ui.control.Label
        mu_1EditField                  matlab.ui.control.NumericEditField
        mu_1EditFieldLabel             matlab.ui.control.Label
        NumperoftapsEditField          matlab.ui.control.NumericEditField
        NumperoftapsEditFieldLabel     matlab.ui.control.Label
        FreqoffsetHzEditField          matlab.ui.control.NumericEditField
        FreqoffsetHzEditFieldLabel     matlab.ui.control.Label
        BackButton                     matlab.ui.control.Button
        OSNRdBEditField                matlab.ui.control.NumericEditField
        OSNRdBEditFieldLabel           matlab.ui.control.Label
        StartSimulationButton          matlab.ui.control.Button
        EqualizationLabel              matlab.ui.control.Label
        LinkModelLabel                 matlab.ui.control.Label
        PolrotationradsEditField       matlab.ui.control.NumericEditField
        PolrotationradsEditFieldLabel  matlab.ui.control.Label
        DeltanuHzEditField             matlab.ui.control.NumericEditField
        DeltanuHzEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        Rs
        r% Description
        EQ_mode
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r, Rs, EQmode)
           if r==1
                app.OSNRdBEditField.Value = 11;
            elseif r==2
                app.OSNRdBEditField.Value = 18;
            else
                app.OSNRdBEditField.Value = 31;
                app.PolrotationradsEditField.Value = 1e2;
            end


            if strcmp(EQmode,'LMS')
                app.N1EditFieldLabel.Text = 'Length pilot seq.';
                app.N2EditFieldLabel.Visible = 'off';
                app.N2EditField.Visible = 'off';
                app.DampingFactorEditFieldLabel.Visible = 'off';
                app.DampingFactorEditField.Visible = 'off';
                app.PhasecompoutputCheckBox.Visible = 'off';
                
                if r==1
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 9;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 9e-4;     
                    
                    else
                        app.NumperoftapsEditField.Value = 11;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 9e-4;
                    end
                elseif r==2
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 21;
                        app.mu_1EditField.Value = 9e-4;
                        app.mu_2EditField.Value = 9e-5;
                    else
                        app.NumperoftapsEditField.Value = 19;
                        app.mu_1EditField.Value = 9e-4;
                        app.mu_2EditField.Value = 9e-5;
                    end
                else
                    app.NumperoftapsEditField.Value = 15;
                    app.mu_1EditField.Value = 9e-4;
                    app.mu_2EditField.Value = 1e-4;                    
                end
            else
                app.N1EditFieldLabel.Text = 'CMA to RDE';
                app.N2EditFieldLabel.Text = 'Change Learn rate';
                if r == 1
                    app.N1EditFieldLabel.Visible = 'off';
                    app.N1EditField.Visible = 'off';
                end

                if r==1
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 11;
                        app.DampingFactorEditField.Value = 31.6;
                    else
                        app.NumperoftapsEditField.Value = 8;
                        app.DampingFactorEditField.Value = 150;
                    end

                    app.mu_1EditField.Value = 8e-3;
                    app.mu_2EditField.Value = 8e-4;

                else
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 15;
                        app.DampingFactorEditField.Value = 11.6;
                        app.mu_1EditField.Value = 8e-3;
                        app.mu_2EditField.Value = 8e-4;

                    elseif Rs == 100
                        app.NumperoftapsEditField.Value = 13;
                        app.DampingFactorEditField.Value = 21.6;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 1e-3;                  

                    else
                        app.NumperoftapsEditField.Value = 13;
                        app.DampingFactorEditField.Value = 11.6;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 1e-3;
                    end
                end

                
                app.N1EditField.Value = 1e3;
                app.N2EditField.Value = 3e5;

            end
            app.Rs = Rs;
            app.r = r;
            app.EQ_mode = EQmode;
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.OptCohScatterplotUIFigure.Visible = 'off';
            if app.SaveFiguresandResultsCheckBox.Value == 1 && isempty(app.ProjectnameEditField.Value)
                app.ProjectnameEditField.Value = 'unnamed_project';
            end
            scatter_vec = [app.ReceiverinputCheckBox.Value,app.CDcompoutputCheckBox.Value,app.FreqcompoutputCheckBox.Value,app.EQoutputCheckBox.Value,app.PhasecompoutputCheckBox.Value];
            f = msgbox(["Simulation is running";"Please wait"]);
            GUI_main_scatterplot(app.r,app.Rs, app.OSNRdBEditField.Value, ...
                app.DeltanuHzEditField.Value, app.PolrotationradsEditField.Value, app.FreqoffsetHzEditField.Value, app.EQ_mode, app.NumperoftapsEditField.Value, app.mu_1EditField.Value, ...
                app.mu_1EditField.Value, app.N1EditField.Value, app.N2EditField.Value, app.DampingFactorEditField.Value, scatter_vec, app.SaveFiguresandResultsCheckBox.Value,app.ProjectnameEditField.Value);
            % function GUI_main_scatterplot(r,Rs, OSNR_dB, delta_nu, rad_sec, EQ_N_tap, EQ_mu, EQ_mu2, EQ_N1, EQ_N2, CarSync_DampFac)
            close(f);
            app.OptCohScatterplotUIFigure.Visible = 'on';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            mainApp = main;
            app.OptCohScatterplotUIFigure.Visible = 'off';
            mainApp.OptCohUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptCohScatterplotUIFigure = uifigure('Visible', 'off');
            app.OptCohScatterplotUIFigure.Name = 'OptCoh - Scatterplot';
            app.OptCohScatterplotUIFigure.Icon = 'OptcohLOGO.png';
            app.DeltanuHzEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.DeltanuHzEditFieldLabel.Interpreter = 'latex';
            app.DeltanuHzEditFieldLabel.Text = '$\Delta \nu$ [Hz]';
            app.DeltanuHzEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.DeltanuHzEditField.Limits = [0 Inf];
            app.DeltanuHzEditField.ValueDisplayFormat = '%1.2e';
            app.DeltanuHzEditField.Value = 50000;
            app.PolrotationradsEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.PolrotationradsEditFieldLabel.Text = 'Pol. rotation [rad/s]';
            app.PolrotationradsEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.PolrotationradsEditField.Limits = [0 Inf];
            app.PolrotationradsEditField.ValueDisplayFormat = '%1.2e';
            app.PolrotationradsEditField.Value = 1000;
            app.LinkModelLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.LinkModelLabel.Text = 'Link Model';
            app.EqualizationLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.EqualizationLabel.Text = 'Equalization';
            app.StartSimulationButton = uibutton(app.OptCohScatterplotUIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.OSNRdBEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.OSNRdBEditFieldLabel.Text = 'OSNR [dB]';
            app.OSNRdBEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.OSNRdBEditField.Limits = [0 Inf];
            app.OSNRdBEditField.Value = 10;
            app.BackButton = uibutton(app.OptCohScatterplotUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.FreqoffsetHzEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.FreqoffsetHzEditFieldLabel.Text = 'Freq. offset [Hz]';
            app.FreqoffsetHzEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.FreqoffsetHzEditField.Value = 1000000;
            app.NumperoftapsEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.NumperoftapsEditFieldLabel.Text = 'Numper of taps';
            app.NumperoftapsEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.NumperoftapsEditField.Limits = [1 Inf];
            app.NumperoftapsEditField.Value = 31;
            app.mu_1EditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.mu_1EditFieldLabel.Interpreter = 'latex';
            app.mu_1EditFieldLabel.Text = '$\mu_1$';
            app.mu_1EditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.mu_1EditField.Limits = [0 1];
            app.mu_1EditField.ValueDisplayFormat = '%1.2e';
            app.mu_1EditField.Value = 1e-05;
            app.mu_2EditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.mu_2EditFieldLabel.Interpreter = 'latex';
            app.mu_2EditFieldLabel.Text = '$\mu_2$';
            app.mu_2EditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.mu_2EditField.Limits = [0 1];
            app.mu_2EditField.ValueDisplayFormat = '%1.2e';
            app.mu_2EditField.Value = 1e-05;
            app.N1EditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.N1EditField.Limits = [0 Inf];
            app.N1EditField.ValueDisplayFormat = '%1.2e';
            app.N1EditField.Value = 100000;
            app.DampingFactorEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.DampingFactorEditFieldLabel.Text = 'Damping Factor';
            app.DampingFactorEditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.DampingFactorEditField.Limits = [0 500];
            app.DampingFactorEditField.Value = 150;
            app.N2EditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.OptCohScatterplotUIFigure, 'numeric');
            app.N2EditField.Limits = [0 Inf];
            app.N2EditField.ValueDisplayFormat = '%1.2e';
            app.N2EditField.Value = 100000;
            app.ScatterplotsLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.ScatterplotsLabel.Text = 'Scatter plots';
            app.ReceiverinputCheckBox = uicheckbox(app.OptCohScatterplotUIFigure);
            app.ReceiverinputCheckBox.Text = 'Receiver input';
            app.CDcompoutputCheckBox = uicheckbox(app.OptCohScatterplotUIFigure);
            app.CDcompoutputCheckBox.Text = 'CD comp. output';
            app.FreqcompoutputCheckBox = uicheckbox(app.OptCohScatterplotUIFigure);
            app.FreqcompoutputCheckBox.Text = 'Freq. comp. output';
            app.EQoutputCheckBox = uicheckbox(app.OptCohScatterplotUIFigure);
            app.EQoutputCheckBox.Text = 'EQ output';
            app.PhasecompoutputCheckBox = uicheckbox(app.OptCohScatterplotUIFigure);
            app.PhasecompoutputCheckBox.Text = 'Phase comp. output';
            app.SaveFiguresandResultsCheckBox = uicheckbox(app.OptCohScatterplotUIFigure);
            app.SaveFiguresandResultsCheckBox.Text = 'Save Figures and Results';
            app.ProjectnameEditFieldLabel = uilabel(app.OptCohScatterplotUIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.OptCohScatterplotUIFigure, 'text');
            % Show the figure after all components are created
            app.OptCohScatterplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scatter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptCohScatterplotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptCohScatterplotUIFigure)
        end
    end
end