classdef deltaSNR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptCohdeltaSNRUIFigure         matlab.ui.Figure
        ProjectnameEditField           matlab.ui.control.EditField
        ProjectnameEditFieldLabel      matlab.ui.control.Label
        SaveFiguresandResultsCheckBox  matlab.ui.control.CheckBox
        N2EditField                    matlab.ui.control.NumericEditField
        N2EditFieldLabel               matlab.ui.control.Label
        DampingFactorEditField         matlab.ui.control.NumericEditField
        DampingFactorEditFieldLabel    matlab.ui.control.Label
        N1EditField                    matlab.ui.control.NumericEditField
        N1EditFieldLabel               matlab.ui.control.Label
        mu_2EditField                  matlab.ui.control.NumericEditField
        mu_2EditFieldLabel             matlab.ui.control.Label
        mu_1EditField                  matlab.ui.control.NumericEditField
        mu_1EditFieldLabel             matlab.ui.control.Label
        NumperoftapsEditField          matlab.ui.control.NumericEditField
        NumperoftapsEditFieldLabel     matlab.ui.control.Label
        AccuracyEditField              matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel         matlab.ui.control.Label
        FreqoffsetHzEditField          matlab.ui.control.NumericEditField
        FreqoffsetHzEditFieldLabel     matlab.ui.control.Label
        BackButton                     matlab.ui.control.Button
        BERgoalEditField               matlab.ui.control.NumericEditField
        BERgoalEditFieldLabel          matlab.ui.control.Label
        IterationlimitEditField        matlab.ui.control.NumericEditField
        IterationlimitEditFieldLabel   matlab.ui.control.Label
        PointspacingDropDown           matlab.ui.control.DropDown
        PointspacingDropDownLabel      matlab.ui.control.Label
        ParametertosweepDropDown       matlab.ui.control.DropDown
        ParametertosweepDropDownLabel  matlab.ui.control.Label
        StartSimulationButton          matlab.ui.control.Button
        EqualizationLabel              matlab.ui.control.Label
        LinkModelLabel                 matlab.ui.control.Label
        SimulationLabel                matlab.ui.control.Label
        PolrotationradsEditField       matlab.ui.control.NumericEditField
        PolrotationradsEditFieldLabel  matlab.ui.control.Label
        DeltanuHzEditField             matlab.ui.control.NumericEditField
        DeltanuHzEditFieldLabel        matlab.ui.control.Label
        NumberofpointsEditField        matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel   matlab.ui.control.Label
        EndvaluesweepEditField         matlab.ui.control.NumericEditField
        OSNRenddBLabel                 matlab.ui.control.Label
        StartvaluesweepEditField       matlab.ui.control.NumericEditField
        StartvaluesweepEditFieldLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        Rs
        r% Description
        EQ_mode
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r, Rs, EQmode)
            if r==3
                app.PolrotationradsEditField.Value = 1e2;
            end


            if strcmp(EQmode,'LMS')
                app.N1EditFieldLabel.Text = 'Length pilot seq.';
                app.N2EditFieldLabel.Visible = 'off';
                app.N2EditField.Visible = 'off';
                app.DampingFactorEditFieldLabel.Visible = 'off';
                app.DampingFactorEditField.Visible = 'off';

                if r==1
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 9;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 9e-4;     
                    
                    else
                        app.NumperoftapsEditField.Value = 11;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 9e-4;
                    end
                elseif r==2
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 21;
                        app.mu_1EditField.Value = 9e-4;
                        app.mu_2EditField.Value = 9e-5;
                    else
                        app.NumperoftapsEditField.Value = 15;
                        app.mu_1EditField.Value = 9e-4;
                        app.mu_2EditField.Value = 9e-5;
                    end
                else
                    app.NumperoftapsEditField.Value = 15;
                    app.mu_1EditField.Value = 9e-4;
                    app.mu_2EditField.Value = 1e-4;                    
                end
            else
                app.N1EditFieldLabel.Text = 'CMA to RDE';
                app.N2EditFieldLabel.Text = 'Change Learn rate';
                if r == 1
                    app.N1EditFieldLabel.Visible = 'off';
                    app.N1EditField.Visible = 'off';
                end

                if r==1
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 11;
                        app.DampingFactorEditField.Value = 31.6;
                    else
                        app.NumperoftapsEditField.Value = 8;
                        app.DampingFactorEditField.Value = 150;
                    end

                    app.mu_1EditField.Value = 8e-3;
                    app.mu_2EditField.Value = 8e-4;

                else
                    if Rs == 64
                        app.NumperoftapsEditField.Value = 15;
                        app.DampingFactorEditField.Value = 11.6;
                        app.mu_1EditField.Value = 8e-3;
                        app.mu_2EditField.Value = 8e-4;

                    elseif Rs == 100
                        app.NumperoftapsEditField.Value = 13;
                        app.DampingFactorEditField.Value = 21.6;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 1e-3;                  

                    else
                        app.NumperoftapsEditField.Value = 13;
                        app.DampingFactorEditField.Value = 11.6;
                        app.mu_1EditField.Value = 9e-3;
                        app.mu_2EditField.Value = 1e-3;
                    end
                end

                
                app.N1EditField.Value = 1e3;
                app.N2EditField.Value = 3e5;

            end
            app.Rs = Rs;
            app.r = r;
            app.EQ_mode = EQmode;
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            if app.StartvaluesweepEditField.Value>app.EndvaluesweepEditField.Value
                f = msgbox("'Start value sweep' must be smaller than 'End value sweep'");
            else
                app.OptCohdeltaSNRUIFigure.Visible = 'off';
                f = uifigure('Name','OptCoh', 'Position', [100, 100, 300, 150]);
                lbl = uilabel(f, 'Text', 'Setting up Simulation', ...
                    'Position', [20, 50, 260, 50], ...
                    'HorizontalAlignment', 'center');
                drawnow;
                GUI_main_deltaSNR(app.r,app.Rs, app.StartvaluesweepEditField.Value, app.EndvaluesweepEditField.Value, app.NumberofpointsEditField.Value, ...
                    app.PointspacingDropDown.Value, app.ParametertosweepDropDown.Value, app.IterationlimitEditField.Value,  app.BERgoalEditField.Value, app.AccuracyEditField.Value,...
                    app.DeltanuHzEditField.Value, app.PolrotationradsEditField.Value, app.FreqoffsetHzEditField.Value, app.EQ_mode, app.NumperoftapsEditField.Value, app.mu_1EditField.Value, ...
                    app.mu_2EditField.Value, app.N1EditField.Value, app.N2EditField.Value, app.DampingFactorEditField.Value, app.SaveFiguresandResultsCheckBox.Value,app.ProjectnameEditField.Value,lbl);
                % function GUI_main_deltaSNR(r,Rs, start_sweep, end_sweep, points_to_sweep, log_or_lin, value2sweep, limit_while, BER_goal, delta_nu, rad_sec, EQ_N_tap, EQ_mu, EQ_mu2, EQ_N1, EQ_N2, CarSync_DampFac)
                close(f);
                app.OptCohdeltaSNRUIFigure.Visible = 'on';
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            mainApp = main;
            app.OptCohdeltaSNRUIFigure.Visible = 'off';
            mainApp.OptCohUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptCohdeltaSNRUIFigure = uifigure('Visible', 'off');
            app.OptCohdeltaSNRUIFigure.Name = 'OptCoh - delta SNR';
            app.OptCohdeltaSNRUIFigure.Icon = 'OptcohLOGO.png';
            app.StartvaluesweepEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.StartvaluesweepEditFieldLabel.Text = 'Start value  sweep';
            app.StartvaluesweepEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.StartvaluesweepEditField.Value = 10;
            app.OSNRenddBLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.OSNRenddBLabel.Text = 'End value  sweep';
            app.EndvaluesweepEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.EndvaluesweepEditField.Value = 1000000;
            app.NumberofpointsEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.NumberofpointsEditField.Limits = [1 Inf];
            app.NumberofpointsEditField.Value = 10;
            app.DeltanuHzEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.DeltanuHzEditFieldLabel.Interpreter = 'latex';
            app.DeltanuHzEditFieldLabel.Text = '$\Delta \nu$ [Hz]';
            app.DeltanuHzEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.DeltanuHzEditField.Limits = [0 Inf];
            app.DeltanuHzEditField.ValueDisplayFormat = '%1.2e';
            app.DeltanuHzEditField.Value = 50000;
            app.PolrotationradsEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.PolrotationradsEditFieldLabel.Text = 'Pol. rotation [rad/s]';
            app.PolrotationradsEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.PolrotationradsEditField.Limits = [0 Inf];
            app.PolrotationradsEditField.ValueDisplayFormat = '%1.2e';
            app.PolrotationradsEditField.Value = 1000;
            app.SimulationLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.SimulationLabel.Text = 'Simulation';
            app.LinkModelLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.LinkModelLabel.Text = 'Link Model';
            app.EqualizationLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.EqualizationLabel.Text = 'Equalization';
            app.StartSimulationButton = uibutton(app.OptCohdeltaSNRUIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ParametertosweepDropDownLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.ParametertosweepDropDownLabel.Text = 'Parameter to sweep';
            app.ParametertosweepDropDown = uidropdown(app.OptCohdeltaSNRUIFigure);
            app.ParametertosweepDropDown.Items = {'delta_nu [Hz]', 'Pol. rotation [rad/s]', 'Freq. offset [Hz]', 'Number of taps', 'mu1', 'mu2', 'N1', 'N2', 'Damping Factor'};
            app.ParametertosweepDropDown.ItemsData = {'delta_nu', 'rad_sec', 'freq_offset', 'EQ_N_tap', 'EQ_mu', 'EQ_mu2', 'EQ_N1', 'EQ_N2', 'CarSync_DampFac'};
            app.ParametertosweepDropDown.Value = 'delta_nu';
            app.PointspacingDropDownLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.PointspacingDropDownLabel.Text = 'Point spacing';
            app.PointspacingDropDown = uidropdown(app.OptCohdeltaSNRUIFigure);
            app.PointspacingDropDown.Items = {'linear', 'logarithmic'};
            app.PointspacingDropDown.ItemsData = {'lin', 'log'};
            app.PointspacingDropDown.Value = 'lin';
            app.IterationlimitEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.IterationlimitEditFieldLabel.Text = 'Iteration limit';
            app.IterationlimitEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.IterationlimitEditField.Limits = [1 Inf];
            app.IterationlimitEditField.Value = 15;
            app.BERgoalEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.BERgoalEditFieldLabel.Text = 'BER goal';
            app.BERgoalEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.BERgoalEditField.Limits = [0 Inf];
            app.BERgoalEditField.ValueDisplayFormat = '%1.2e';
            app.BERgoalEditField.Value = 0.001;
            app.BackButton = uibutton(app.OptCohdeltaSNRUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.FreqoffsetHzEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.FreqoffsetHzEditFieldLabel.Text = 'Freq. offset [Hz]';
            app.FreqoffsetHzEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.FreqoffsetHzEditField.Value = 1000000;
            app.AccuracyEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.AccuracyEditFieldLabel.Text = 'Accuracy [%]';
            app.AccuracyEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.AccuracyEditField.Value = 5;
            app.NumperoftapsEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.NumperoftapsEditFieldLabel.Text = 'Numper of taps';
            app.NumperoftapsEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.NumperoftapsEditField.Limits = [1 Inf];
            app.NumperoftapsEditField.Value = 31;
            app.mu_1EditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.mu_1EditFieldLabel.Interpreter = 'latex';
            app.mu_1EditFieldLabel.Text = '$\mu_1$';
            app.mu_1EditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.mu_1EditField.Limits = [0 1];
            app.mu_1EditField.ValueDisplayFormat = '%1.2e';
            app.mu_1EditField.Value = 1e-05;
            app.mu_2EditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.mu_2EditFieldLabel.Interpreter = 'latex';
            app.mu_2EditFieldLabel.Text = '$\mu_2$';
            app.mu_2EditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.mu_2EditField.Limits = [0 1];
            app.mu_2EditField.ValueDisplayFormat = '%1.2e';
            app.mu_2EditField.Value = 1e-05;
            app.N1EditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.N1EditField.Limits = [0 Inf];
            app.N1EditField.ValueDisplayFormat = '%1.2e';
            app.N1EditField.Value = 100000;
            app.DampingFactorEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.DampingFactorEditFieldLabel.Text = 'Damping Factor';
            app.DampingFactorEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.DampingFactorEditField.Limits = [0 500];
            app.DampingFactorEditField.Value = 150;
            app.N2EditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'numeric');
            app.N2EditField.Limits = [0 Inf];
            app.N2EditField.ValueDisplayFormat = '%1.2e';
            app.N2EditField.Value = 100000;
            app.SaveFiguresandResultsCheckBox = uicheckbox(app.OptCohdeltaSNRUIFigure);
            app.SaveFiguresandResultsCheckBox.Text = 'Save Figures and Results';
            app.ProjectnameEditFieldLabel = uilabel(app.OptCohdeltaSNRUIFigure);
            app.ProjectnameEditFieldLabel.Text = 'Project name';
            app.ProjectnameEditField = uieditfield(app.OptCohdeltaSNRUIFigure, 'text');
            % Show the figure after all components are created
            app.OptCohdeltaSNRUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deltaSNR(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptCohdeltaSNRUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptCohdeltaSNRUIFigure)
        end
    end
end