classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptCohUIFigure                 matlab.ui.Figure
        EqualizerDropDown              matlab.ui.control.DropDown
        EqualizerDropDownLabel         matlab.ui.control.Label
        ContinueButton                 matlab.ui.control.Button
        ModeDropDown                   matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        BaudrateGBdDropDown            matlab.ui.control.DropDown
        BaudrateGBdDropDownLabel       matlab.ui.control.Label
        ModulationSchemeDropDown       matlab.ui.control.DropDown
        ModulationSchemeDropDownLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function startupFcn(app)
            app.OptCohUIFigure.Name = 'OPTCOH-24   GUI';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            if strcmp(app.ModulationSchemeDropDown.Value, '64-QAM') && (strcmp(app.BaudrateGBdDropDown.Value, '100') || strcmp(app.EqualizerDropDown.Value, 'CMA/RDE'))             
                f = msgbox(["100GBd or CMA/RDE not available for 64-QAM"]);
            else
                if strcmp(app.ModulationSchemeDropDown.Value, '4-QPSK') 
                    r=1;
                elseif strcmp(app.ModulationSchemeDropDown.Value, '16-QAM')
                    r=2;
                else
                    r=3;
                end
                Rs = str2double(app.BaudrateGBdDropDown.Value);

                if app.ModeDropDown.Value == "Bit Error Curve"
                    berApp  = BER(r, Rs, app.EqualizerDropDown.Value);
                    app.OptCohUIFigure.Visible = 'off'; 
                    berApp.OptCohBERcurveUIFigure.Visible = 'on';
                elseif app.ModeDropDown.Value == "SNR penalty"
                    snrApp  = deltaSNR(r, Rs, app.EqualizerDropDown.Value);
                    app.OptCohUIFigure.Visible = 'off';
                    snrApp.OptCohdeltaSNRUIFigure.Visible = 'on';
                else
                    scatterApp = Scatter(r, Rs, app.EqualizerDropDown.Value);
                    app.OptCohUIFigure.Visible = 'off';
                    scatterApp.OptCohScatterplotUIFigure.Visible = 'on';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OptCohUIFigure = uifigure('Visible', 'off');
            app.OptCohUIFigure.Name = 'OptCoh';
            app.OptCohUIFigure.Icon = fullfile(pathToMLAPP, 'OptcohLOGO.png');
            app.ModulationSchemeDropDownLabel = uilabel(app.OptCohUIFigure);
            app.ModulationSchemeDropDownLabel.Text = 'Modulation Scheme';
            app.ModulationSchemeDropDown = uidropdown(app.OptCohUIFigure);
            app.ModulationSchemeDropDown.Items = {'4-QPSK', '16-QAM', '64-QAM'};
            app.ModulationSchemeDropDown.Value = '4-QPSK';
            app.BaudrateGBdDropDownLabel = uilabel(app.OptCohUIFigure);
            app.BaudrateGBdDropDownLabel.Text = 'Baud rate [GBd]';
            app.BaudrateGBdDropDown = uidropdown(app.OptCohUIFigure);
            app.BaudrateGBdDropDown.Items = {'64', '100', '128'};
            app.BaudrateGBdDropDown.Value = '64';
            app.ModeDropDownLabel = uilabel(app.OptCohUIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.OptCohUIFigure);
            app.ModeDropDown.Items = {'Bit Error Curve', 'SNR penalty', 'Scatter plot'};
            app.ModeDropDown.Value = 'Bit Error Curve';
            app.ContinueButton = uibutton(app.OptCohUIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.EqualizerDropDownLabel = uilabel(app.OptCohUIFigure);
            app.EqualizerDropDownLabel.Text = 'Equalizer';
            app.EqualizerDropDown = uidropdown(app.OptCohUIFigure);
            app.EqualizerDropDown.Items = {'LMS', 'CMA/RDE'};
            app.EqualizerDropDown.Value = 'LMS';
            % Show the figure after all components are created
            app.OptCohUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptCohUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptCohUIFigure)
        end
    end
end